classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EscribirtextoparaesconderButton  matlab.ui.control.Button
        Label            matlab.ui.control.Label
        ContinuarButton  matlab.ui.control.Button
        Image2           matlab.ui.control.Image
        ElegirlaimagenquedeseaesconderButton  matlab.ui.control.Button
        Image            matlab.ui.control.Image
        ElegirimagendndedeseeesconderelmensajeButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        band = 0
        band2 = 0
        img1
        img2
        %properties para poder pasar las variables de funcipona función  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % ElegirimagendndedeseeesconderelmensajeButton
        function ElegirimagendndedeseeesconderelmensajeButtonPushed(app, event)
            try
                [filename, pathname] = uigetfile({'*','Imagen'}, 'Seleccione una imagen');
                app.img1 =  imread(filename);
                app.Image.ImageSource = app.img1;
                app.img1=imresize(app.img1,[300,300]);
                app.img1=rgb2gray(app.img1);
                app.band = 1;
                app.Label.Text = ' ';
            catch
                app.Label.Text = 'Seleccione otra imagen';
            end
        end

        % Button pushed function: ElegirlaimagenquedeseaesconderButton
        function ElegirlaimagenquedeseaesconderButtonPushed(app, event)
            try
                [filename2, pathname] = uigetfile({'*','Imagen'}, 'Seleccione una imagen');
                app.img2=imread(filename2);
                app.Image2.ImageSource = app.img2;
                app.img2=imresize(app.img2,[300,300]);
                app.img2=rgb2gray(app.img2);
                app.band2 = 1;
                app.Label.Text = ' ';
            catch
                app.Label.Text = 'Seleccione otra imagen';
            end
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            try
                if app.band == 1 && app.band2 == 1 % Para saber si cargaron las dos imágenes
                    alpha=0.02;
                    A=dct2(double(app.img1));
                    B=dct2(double(app.img2));
                    C=A+alpha*B;
                    %figure();
                    %subplot(1,3,1);imshow(log(A));title('DCT Image');
                    %subplot(1,3,2);imshow(log(B));title('DCT Logo');
                    %subplot(1,3,3);imshow(log(C));title('DCT Watermarks');
    
                    iw=round(abs(idct2(C)));
                    figure();imshow(uint8(iw));title('Watermarked image');
    
                    C1=dct2(double(iw));
    
                    B1=(C1-A)./alpha;
                    wrecuperada=uint8(idct2(B1));
                    figure();imshow(wrecuperada);title('Imagen recuperado');
                    app.Label.Text = ' ';
                else
                    app.Label.Text = 'Seleccione las imágenes o el texto';
                end
            catch
                app.Label.Text = 'Ocurrió un error, intente de nuevo';
            end

        end

        % Button pushed function: EscribirtextoparaesconderButton
        function EscribirtextoparaesconderButtonPushed(app, event)
            try
                text = input('Ingrese el texto: ', 's');
                hold on;
                [filename3, pathname] = uigetfile({'*','Imagen'}, 'Seleccione una imagen de fondo para el texto');
                I = imread(filename3);
                [ySize, xSize] = size(I);
                if ySize > xSize
                    FS = round(ySize/30);
                else
                    FS = round(xSize/30);
                end
                if FS > 200
                    FS = 200;
                end
                disp(FS);
                position = [5 5];
                RGB = insertText(I,position,text, 'FontSize', FS, 'BoxColor','white','BoxOpacity',1); 
                app.img2=RGB;
                app.img2=imresize(app.img2,[300,300]);
                app.img2=rgb2gray(app.img2);
                app.Image2.ImageSource = RGB;
                app.band2 = 1;
                app.Label.Text = ' ';
            catch
                app.Label.Text = 'Ocurrió un error, intente de nuevo';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ElegirimagendndedeseeesconderelmensajeButton = uibutton(app.UIFigure, 'push');
            app.ElegirimagendndedeseeesconderelmensajeButton.ButtonPushedFcn = createCallbackFcn(app, @ElegirimagendndedeseeesconderelmensajeButtonPushed, true);
            app.ElegirimagendndedeseeesconderelmensajeButton.Text = {'Elegir imagen dónde desee'; 'esconder el mensaje'};
            app.Image = uiimage(app.UIFigure);
            app.ElegirlaimagenquedeseaesconderButton = uibutton(app.UIFigure, 'push');
            app.ElegirlaimagenquedeseaesconderButton.ButtonPushedFcn = createCallbackFcn(app, @ElegirlaimagenquedeseaesconderButtonPushed, true);
            app.ElegirlaimagenquedeseaesconderButton.Text = {'Elegir la imagen que'; 'desea esconder'};
            app.Image2 = uiimage(app.UIFigure);
            app.ContinuarButton = uibutton(app.UIFigure, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EscribirtextoparaesconderButton = uibutton(app.UIFigure, 'push');
            app.EscribirtextoparaesconderButton.ButtonPushedFcn = createCallbackFcn(app, @EscribirtextoparaesconderButtonPushed, true);
            app.EscribirtextoparaesconderButton.Text = {'Escribir texto'; 'para esconder'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end